classdef SugarCaneLeafDiseaseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputUIAxes                     matlab.ui.control.UIAxes
        GradientMagnitudeUIAxes         matlab.ui.control.UIAxes
        LabUIAxes                       matlab.ui.control.UIAxes
        cluster1UIAxes                  matlab.ui.control.UIAxes
        ReadInputImageButton            matlab.ui.control.Button
        GrayPreprocessButton            matlab.ui.control.Button
        RGBtoLabButton                  matlab.ui.control.Button
        OtsuThresholdingButton          matlab.ui.control.Button
        KmeansClustringButton           matlab.ui.control.Button
        DetectionGrayScaleButton        matlab.ui.control.Button
        DetectionColorSpaceButton       matlab.ui.control.Button
        FeatureExtractionGrayButton     matlab.ui.control.Button
        FeatureExtractionColorButton    matlab.ui.control.Button
        ClassificationGrayButton        matlab.ui.control.Button
        ClassificationColorButton       matlab.ui.control.Button
        cluster2UIAxes                  matlab.ui.control.UIAxes
        cluster3UIAxes                  matlab.ui.control.UIAxes
        OtsuThresholdingUIAxes          matlab.ui.control.UIAxes
        ROIGrayUIAxes                   matlab.ui.control.UIAxes
        ROIColorUIAxes                  matlab.ui.control.UIAxes
        TextArea                        matlab.ui.control.TextArea
        FeaturesGrayTextArea            matlab.ui.control.TextArea
        ContrastTextAreaLabel           matlab.ui.control.Label
        ContrastTextArea                matlab.ui.control.TextArea
        CorrelationTextAreaLabel        matlab.ui.control.Label
        CorrelationTextArea             matlab.ui.control.TextArea
        EnergyTextAreaLabel             matlab.ui.control.Label
        EnergyTextArea                  matlab.ui.control.TextArea
        HomogeneityTextAreaLabel        matlab.ui.control.Label
        HomogeneityTextArea             matlab.ui.control.TextArea
        MeanTextAreaLabel               matlab.ui.control.Label
        MeanTextArea                    matlab.ui.control.TextArea
        EntropyTextAreaLabel            matlab.ui.control.Label
        EntropyTextArea                 matlab.ui.control.TextArea
        StandardDeviationTextAreaLabel  matlab.ui.control.Label
        StandardDeviationTextArea       matlab.ui.control.TextArea
        RMSTextAreaLabel                matlab.ui.control.Label
        RMSTextArea                     matlab.ui.control.TextArea
        VarianceTextAreaLabel           matlab.ui.control.Label
        VarianceTextArea                matlab.ui.control.TextArea
        SmoothnessTextAreaLabel         matlab.ui.control.Label
        SmoothnessTextArea              matlab.ui.control.TextArea
        KurthosisTextAreaLabel          matlab.ui.control.Label
        KurthosisTextArea               matlab.ui.control.TextArea
        SkewnessTextAreaLabel           matlab.ui.control.Label
        SkewnessTextArea                matlab.ui.control.TextArea
        FeaturesGrayTextArea_2          matlab.ui.control.TextArea
        ContrastTextArea_2Label         matlab.ui.control.Label
        ContrastTextArea_2              matlab.ui.control.TextArea
        CorrelationTextArea_2Label      matlab.ui.control.Label
        CorrelationTextArea_2           matlab.ui.control.TextArea
        EnergyTextArea_2Label           matlab.ui.control.Label
        EnergyTextArea_2                matlab.ui.control.TextArea
        HomogeneityTextArea_2Label      matlab.ui.control.Label
        HomogeneityTextArea_2           matlab.ui.control.TextArea
        StandardDeviationTextArea_2Label  matlab.ui.control.Label
        StandardDeviationTextArea_2     matlab.ui.control.TextArea
        EntropyTextArea_2Label          matlab.ui.control.Label
        EntropyTextArea_2               matlab.ui.control.TextArea
        MeanTextArea_2Label             matlab.ui.control.Label
        MeanTextArea_2                  matlab.ui.control.TextArea
        VarianceTextArea_2Label         matlab.ui.control.Label
        VarianceTextArea_2              matlab.ui.control.TextArea
        SmoothnessTextArea_2Label       matlab.ui.control.Label
        SmoothnessTextArea_2            matlab.ui.control.TextArea
        SkewnessTextArea_2Label         matlab.ui.control.Label
        SkewnessTextArea_2              matlab.ui.control.TextArea
        KurthosisTextArea_2Label        matlab.ui.control.Label
        KurthosisTextArea_2             matlab.ui.control.TextArea
        IDMTextAreaLabel                matlab.ui.control.Label
        IDMTextArea                     matlab.ui.control.TextArea
        RMSTextArea_2Label              matlab.ui.control.Label
        RMSTextArea_2                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        inputImage; % Description
        gradmag;
        lab;
        otsu;
        cluster1;
        cluster2;
        cluster3;
        binary;
        edge_color;
        feat_disease_gray;
        B;
        feat_color_disease;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadInputImageButton
        function ReadInputImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.gif'}, 'Pick a Leaf Image File');
            I = imread([pathname,filename]);
            I = imresize(I,[256,256]);
            imshow(I ,'Parent',app.InputUIAxes);
            app.inputImage=I;
        end

        % Button pushed function: GrayPreprocessButton
        function GrayPreprocessButtonPushed(app, event)
            input=app.inputImage;
            I = rgb2gray(input);
            
            
            I = imresize(I,[256,256]);
            hy = fspecial('sobel');
            hx = hy';
            Iy = imfilter(double(I), hy, 'replicate');
            Ix = imfilter(double(I), hx, 'replicate');
            app.gradmag = sqrt(Ix.^2 + Iy.^2);
            imshow(app.gradmag,[] ,'Parent',app.GradientMagnitudeUIAxes);
        end

        % Button pushed function: RGBtoLabButton
        function RGBtoLabButtonPushed(app, event)
            input=app.inputImage;
            I=imresize(input,[256 256]);
            cform = makecform('srgb2lab');
            app.lab = applycform(I,cform);
            imshow(app.lab ,'Parent',app.LabUIAxes);
        end

        % Button pushed function: OtsuThresholdingButton
        function OtsuThresholdingButtonPushed(app, event)
            I=app.gradmag;
            [ T ] = otsurec(I, 8);
            ttotal=8;
            [mt]=size(T);
            
            I1=I;
            [N_St] = Normalizefunction(I1);
            figure,
            for i=1:mt
                if i==ttotal
                    t2=1;
                    %t2
                else
                    t2=T(i+1);
                    %t2
                end
                t1=T(i);
                %t1
                [ Ib ] = binimage(N_St,t1,t2);
                %Store the resultent bimary image in the following folder, before tht
                %you have to create that folder....
                %dest='D:\An efficient algorithm for fractal analysis of textures\image\';
                fs1=strcat(int2str(i),'.bmp');
                imwrite(Ib, fs1);
                subplot(2,4,i),imshow(Ib),title('Otsu Threshold Image');
            end
            x = inputdlg('Enter Otsu threshold image number {4 or 6 or 8}:');
            k = str2num(x{:});
            
            switch(k)
                case 4 
                    x=4;
                    inimg=strcat(int2str(x),'.bmp');
                    img=imread(inimg);
                    img1=~img;
                    imshow(img1 ,'Parent',app.OtsuThresholdingUIAxes);
                    app.otsu=img1;
                case 6
                    x=6;
                    inimg=strcat(int2str(x),'.bmp');
                    img=imread(inimg);
                    img2=~img;
                    imshow(img2 ,'Parent',app.OtsuThresholdingUIAxes);
                    app.otsu=img2;
                case 8
                    x=8;
                    inimg=strcat(int2str(x),'.bmp');
                    img3=imread(inimg);
                    imshow(img3 ,'Parent',app.OtsuThresholdingUIAxes);
                    app.otsu=img3;
                otherwise
                    x=8;
                    inimg=strcat(int2str(x),'.bmp');
                    img3=imread(inimg);
                    imshow(img3 ,'Parent',app.OtsuThresholdingUIAxes);
                    app.otsu=img3;
            end
        end

        % Button pushed function: KmeansClustringButton
        function KmeansClustringButtonPushed(app, event)
            lab_he=app.lab;
            I=app.inputImage;
            ab = double(lab_he(:,:,2:3));
            nrows = size(ab,1);
            ncols = size(ab,2);
            ab = reshape(ab,nrows*ncols,2);

            I=imresize(I,[256 256]);
            
            nColors = 3;
            % repeat the clustering 3 times to avoid local minima
            [cluster_idx, cluster_center] = kmeans(ab,nColors,'distance','sqEuclidean', ...
                                                  'Replicates',3);
            pixel_labels = reshape(cluster_idx,nrows,ncols);
            
            segmented_images = cell(1,3);
            rgb_label = repmat(pixel_labels,[1 1 3]);
            
            for k = 1:nColors
                color = I;
                color(rgb_label ~= k) = 0;
                segmented_images{k} = color;
                fs1=strcat(int2str(k),'.bmp');
                imwrite(segmented_images{k}, fs1);
            end
            imshow(segmented_images{1} ,'Parent',app.cluster1UIAxes);
            app.cluster1=segmented_images{1};
               
            imshow(segmented_images{2} ,'Parent',app.cluster2UIAxes);
            app.cluster2=segmented_images{2};
            
            imshow(segmented_images{3} ,'Parent',app.cluster3UIAxes);
            app.cluster3=segmented_images{3};
        end

        % Button pushed function: DetectionGrayScaleButton
        function DetectionGrayScaleButtonPushed(app, event)
            img=app.otsu;
            I=app.inputImage;
            s=strel('disk',2,0);
            I16=imerode(img,s);
            I17=(img-I16);
            
            AVG_NFT=I17;
            A=imresize(I,[256 256]);
            bin = im2bw(A,0.5);
            [m,n]=size(AVG_NFT);
            MFC1=0;
            for i=1:m
                for j=1:n
                    MFC1=MFC1+double(AVG_NFT(i,j));
                end
            end
            MFC=(MFC1/(m*n));
            
            count=0;
            for i=1:m
                for j=1:n
                    
                    if AVG_NFT(i,j) >= MFC
                        count=count+1;
                    end
                end
            end
            
           
            SFC=MFC1;
            NHF=count;
            T=(SFC/((m*n)+NHF));
            
            T=uint8(T);
            for i=1:m
                for j=1:n
                    
                    if (AVG_NFT(i,j)>T && bin(i,j)==1)
                        bin(i,j)=1;
                        res(i,j)=1;
                    else
                        bin(i,j)=0;
                        res(i,j)=0;
                    end
                    
                end
            end
            
            B=res;
            bin=B;
            app.binary=bin;
            imshow(app.binary ,'Parent',app.ROIGrayUIAxes);
        end

        % Button pushed function: DetectionColorSpaceButton
        function DetectionColorSpaceButtonPushed(app, event)
            x = inputdlg('Enter Cluster number {1 or 2 or 3}:');
            k = str2num(x{:}); 
            if k==1
                Cluster_selected=app.cluster1;
            end
            if k==2
                Cluster_selected=app.cluster2;
            end
            if k==3
                Cluster_selected=app.cluster3;
            end
            
        g1=rgb2gray(Cluster_selected);
         
            A=g1;
            AVG_NFT=g1;
            bin = im2bw(A,0.5);
           
            [m,n]=size(AVG_NFT);
            MFC1=0;
            for i=1:m
                for j=1:n
                    MFC1=MFC1+double(AVG_NFT(i,j));
                end
            end
            MFC=(MFC1/(m*n));

            count=0;
            for i=1:m
                for j=1:n

                    if AVG_NFT(i,j) >= MFC
                        count=count+1;
                    end
                end
            end

            SFC=MFC1;
            NHF=count;
            T=(SFC/((m*n)+NHF));

            T=uint8(T);
            for i=1:m
                for j=1:n

                    if (AVG_NFT(i,j)>T && bin(i,j)==1)
                        bin(i,j)=1;
                        res(i,j)=1;
                    else
                        bin(i,j)=0;
                        res(i,j)=0;
                    end

                end
            end

            app.B=res;

            g1=rgb2gray(Cluster_selected);
            e1=edge(g1,'sobel');
            
            imshow(e1 ,'Parent',app.ROIColorUIAxes);
            app.edge_color=e1;
        end

        % Button pushed function: FeatureExtractionGrayButton
        function FeatureExtractionGrayButtonPushed(app, event)
            I19=app.binary;
            glcms = graycomatrix(I19);
            % Derive Statistics from GLCM
            stats = graycoprops(glcms,'Contrast Correlation Energy Homogeneity');
            Contrast = stats.Contrast;
            Correlation = stats.Correlation;
            Energy = stats.Energy;
            Homogeneity = stats.Homogeneity;
            Mean = mean2(I19);
            Standard_Deviation = std2(I19);
            Entropy = entropy(I19);
            RMS = mean2(rms(I19));
            Variance = mean2(var(double(I19)));
            a = sum(double(I19(:)));
            Smoothness = 1-(1/(1+a));
            Kurtosis = kurtosis(double(I19(:)));
            Skewness = skewness(double(I19(:)));
            % Inverse Difference Movement
            m = size(I19,1);
            n = size(I19,2);
            in_diff = 0;
            for i = 1:m
                for j = 1:n
                    temp = I19(i,j)./(1+(i-j).^2);
                    in_diff = in_diff+temp;
                end
            end
            IDM = double(in_diff);
            app.feat_disease_gray = [Contrast,Correlation,Energy,Homogeneity, Mean, Standard_Deviation, Entropy, RMS, Variance, Smoothness, Kurtosis, Skewness, IDM];
            
            app.ContrastTextArea.Value=string(app.feat_disease_gray(1,1));
            app.CorrelationTextArea.Value=string(app.feat_disease_gray(1,2));
            app.EnergyTextArea.Value=string(app.feat_disease_gray(1,3));
            app.HomogeneityTextArea.Value=string(app.feat_disease_gray(1,4));
            app.MeanTextArea.Value=string(app.feat_disease_gray(1,5));
            app.StandardDeviationTextArea.Value=string(app.feat_disease_gray(1,6));
            app.EntropyTextArea.Value=string(app.feat_disease_gray(1,7));
            app.RMSTextArea.Value=string(app.feat_disease_gray(1,8));
            app.VarianceTextArea.Value=string(app.feat_disease_gray(1,9));
            app.SmoothnessTextArea.Value=string(app.feat_disease_gray(1,10));
            app.KurthosisTextArea.Value=string(app.feat_disease_gray(1,11));
            app.SkewnessTextArea.Value=string(app.feat_disease_gray(1,12));
            app.IDMTextArea.Value=string(app.feat_disease_gray(1,13));
        end

        % Button pushed function: FeatureExtractionColorButton
        function FeatureExtractionColorButtonPushed(app, event)
            I19=app.edge_color;
            glcms = graycomatrix(I19);
            % Derive Statistics from GLCM
            stats = graycoprops(glcms,'Contrast Correlation Energy Homogeneity');
            Contrast = stats.Contrast;
            Correlation = stats.Correlation;
            Energy = stats.Energy;
            Homogeneity = stats.Homogeneity;
            Mean = mean2(I19);
            Standard_Deviation = std2(I19);
            Entropy = entropy(I19);
            %rms(I19);
            %RMS = mean2(rms(I19));
            Variance = mean2(var(double(I19)));
            a = sum(double(I19(:)));
            Smoothness = 1-(1/(1+a));
            Kurtosis = kurtosis(double(I19(:)));
            Skewness = skewness(double(I19(:)));
            % Inverse Difference Movement
            m = size(I19,1);
            n = size(I19,2);
            in_diff = 0;
            for i = 1:m
                for j = 1:n
                    temp = I19(i,j)./(1+(i-j).^2);
                    in_diff = in_diff+temp;
                end
            end
            IDM = double(in_diff);
            
            I19=app.B;
            glcms = graycomatrix(I19);
            % Derive Statistics from GLCM
            stats = graycoprops(glcms,'Contrast Correlation Energy Homogeneity');
            Contrast1 = stats.Contrast;
            Correlation1 = stats.Correlation;
            Energy1 = stats.Energy;
            Homogeneity1 = stats.Homogeneity;
            Mean1 = mean2(I19);
            Standard_Deviation1 = std2(I19);
            Entropy1 = entropy(I19);
            %rms(I19);
            %RMS = mean2(rms(I19));
            Variance1 = mean2(var(double(I19)));
            a = sum(double(I19(:)));
            Smoothness1 = 1-(1/(1+a));
            Kurtosis1 = kurtosis(double(I19(:)));
            Skewness1 = skewness(double(I19(:)));
            % Inverse Difference Movement
            m = size(I19,1);
            n = size(I19,2);
            in_diff = 0;
            for i = 1:m
                for j = 1:n
                    temp = I19(i,j)./(1+(i-j).^2);
                    in_diff = in_diff+temp;
                end
            end
            IDM1 = double(in_diff);
            app.feat_color_disease = [Contrast,Correlation,Energy,Homogeneity, Mean, Standard_Deviation, Entropy, Variance, Smoothness, Kurtosis, Skewness, IDM,Contrast1,Correlation1,Energy1,Homogeneity1, Mean1, Standard_Deviation1, Entropy1, Variance1, Smoothness1, Kurtosis1, Skewness1, IDM1];
            
            app.ContrastTextArea_2.Value=string(app.feat_color_disease(1,1));
            app.CorrelationTextArea_2.Value=string(app.feat_color_disease(1,2));
            app.EnergyTextArea_2.Value=string(app.feat_color_disease(1,3));
            app.HomogeneityTextArea_2.Value=string(app.feat_color_disease(1,4));
            app.MeanTextArea_2.Value=string(app.feat_color_disease(1,5));
            app.StandardDeviationTextArea_2.Value=string(app.feat_color_disease(1,6));
            app.EntropyTextArea_2.Value=string(app.feat_color_disease(1,7));
            app.RMSTextArea_2.Value=string(app.feat_color_disease(1,8));
            app.VarianceTextArea_2.Value=string(app.feat_color_disease(1,9));
            app.SmoothnessTextArea_2.Value=string(app.feat_color_disease(1,10));
            app.KurthosisTextArea_2.Value=string(app.feat_color_disease(1,11));
            app.SkewnessTextArea_2.Value=string(app.feat_color_disease(1,12));
            
        end

        % Button pushed function: ClassificationGrayButton
        function ClassificationGrayButtonPushed(app, event)
            
            feat_disease=app.feat_disease_gray;
            load svmStructRIRU
            load svmStructRUYE
            load svmStructRIYE
             
            result = zeros(1,3);
            tmpResult = predict(svmStructRIRU,feat_disease); 
            if(tmpResult == 0)
                result(1,1) = result(1,1) + 1;
            else
                result(1,2) = result(1,2) + 1;
            end
            tmpResult = predict(svmStructRUYE,feat_disease);
            if(tmpResult == 1)
                result(1,2) = result(1,2) + 1;
            else
                result(1,3) = result(1,3) + 1;
            end
            tmpResult = predict(svmStructRIYE,feat_disease);
            if(tmpResult == 0)
                result(1,1) = result(1,1) + 1;
            else
                result(1,3) = result(1,3) + 1;
            end
             
            [maxValue, maxIndex] = max(result);
            switch maxIndex
                case 1
                    finalResult = 'Ring Spot';
                    pause(1)
                    msgbox({'DISEASE AFFECTED:        Ring Spot';'';'';' CAUSING AGENT:        Leptosphaeria sacchari Phyllosticta .';'';'';' 	SYMPTOMS ARE';'';'';'1) Initial symptoms of ring spot are small, elongated, oval-shaped spots that are dark olivaceous green to reddish- brown with narrow yellow halos ';'';'';'2) Typical older symptoms of ring spot are large and elongated lesions (2.5 to 5 mm x 10 to 18 mm) with irregular outlines and red-brown margins ';'';'';'';});
                        
                case 2
                    finalResult = 'Sugar cane Rust';
                    pause(1)
                    msgbox({'DISEASE AFFECTED:       Sugar cane Rust';'';'';' CAUSING AGENT:        Puccinia melanocephala ';'';'';' 	SYMPTOMS ARE';'';'';'1) The earliest symptoms of common rust on the leaves are small, elongated yellowish spots which are visible on both the surfaces. These spots increase in size, mainly in length, and turn red-brown to brown in color. A narrow, pale yellow-green halo develops around the lesions.';'';'';' 2) Affected crops take on a distinctive rusty to brown color, as disease intensity builds up. The discoloration of these crops is readily seen from a distance.';'';'';'';});
                    
                case 3
                    finalResult = 'Yellow Spot';
                    pause(1)
                    msgbox({'DISEASE AFFECTED:        Yellow spot';'';'';' CAUSING AGENT:        Cercospora Koepkei';'';'';' 	SYMPTOMS ARE';'';'';'1) Presence of small, yellow coloured, irregularly shaped spots over the leaf surface. Density of spots is minimum in the lower surface, moderate in the middle and maximum towards the tip of the leaf.';'';'';'2) Spots coalesce at late stages and cause drying of leaves. Badly affected foliage looks reddish-brown when viewed from a distance.';'';'';'';});
                    
            end
        end

        % Button pushed function: ClassificationColorButton
        function ClassificationColorButtonPushed(app, event)
                        
            load svmStructColorRIRU_Final
            load svmStructColorRUYE_Final
            load svmStructColorRIYE_Final
             
            result = zeros(1,3);
            tmpResult = predict(svmStructColorRIRU,app.feat_color_disease);
            if(tmpResult == 0)
                result(1,1) = result(1,1) + 1;
            else
                result(1,2) = result(1,2) + 1;
            end
            tmpResult = predict(svmStructColorRUYE,app.feat_color_disease);
            if(tmpResult == 1)
                result(1,2) = result(1,2) + 1;
            else
                result(1,3) = result(1,3) + 1;
            end
            tmpResult = predict(svmStructColorRIYE,app.feat_color_disease);
            if(tmpResult == 0)
                result(1,1) = result(1,1) + 1;
            else
                result(1,3) = result(1,3) + 1;
            end
             
            [maxValue, maxIndex] = max(result);
            switch maxIndex
                case 1
                    finalResult = 'Ring Spot';
                    pause(1)
                    msgbox({'DISEASE AFFECTED:        Ring Spot';'';'';' CAUSING AGENT:        Leptosphaeria sacchari Phyllosticta .';'';'';' 	SYMPTOMS ARE';'';'';'1) Initial symptoms of ring spot are small, elongated, oval-shaped spots that are dark olivaceous green to reddish- brown with narrow yellow halos ';'';'';'2) Typical older symptoms of ring spot are large and elongated lesions (2.5 to 5 mm x 10 to 18 mm) with irregular outlines and red-brown margins ';'';'';'';});
                        
                case 2
                    finalResult = 'Sugar cane Rust';
                    pause(1)
                    msgbox({'DISEASE AFFECTED:       Sugar cane Rust';'';'';' CAUSING AGENT:        Puccinia melanocephala ';'';'';' 	SYMPTOMS ARE';'';'';'1) The earliest symptoms of common rust on the leaves are small, elongated yellowish spots which are visible on both the surfaces. These spots increase in size, mainly in length, and turn red-brown to brown in color. A narrow, pale yellow-green halo develops around the lesions.';'';'';' 2) Affected crops take on a distinctive rusty to brown color, as disease intensity builds up. The discoloration of these crops is readily seen from a distance.';'';'';'';});
                    
                case 3
                    finalResult = 'Yellow Spot';
                    pause(1)
                    msgbox({'DISEASE AFFECTED:        Yellow spot';'';'';' CAUSING AGENT:        Cercospora Koepkei';'';'';' 	SYMPTOMS ARE';'';'';'1) Presence of small, yellow coloured, irregularly shaped spots over the leaf surface. Density of spots is minimum in the lower surface, moderate in the middle and maximum towards the tip of the leaf.';'';'';'2) Spots coalesce at late stages and cause drying of leaves. Badly affected foliage looks reddish-brown when viewed from a distance.';'';'';'';});
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputUIAxes = uiaxes(app.UIFigure);
            title(app.InputUIAxes, 'Input Image')
            xlabel(app.InputUIAxes, '')
            ylabel(app.InputUIAxes, '')
            app.InputUIAxes.XTick = [];
            app.InputUIAxes.XTickLabel = '';
            app.InputUIAxes.YTick = [];
            app.InputUIAxes.YTickLabel = '';
            app.GradientMagnitudeUIAxes = uiaxes(app.UIFigure);
            title(app.GradientMagnitudeUIAxes, 'Gradient Magnitude ')
            xlabel(app.GradientMagnitudeUIAxes, '')
            ylabel(app.GradientMagnitudeUIAxes, '')
            app.GradientMagnitudeUIAxes.XTick = [];
            app.GradientMagnitudeUIAxes.XTickLabel = '';
            app.GradientMagnitudeUIAxes.YTick = [];
            app.GradientMagnitudeUIAxes.YTickLabel = '';
            app.LabUIAxes = uiaxes(app.UIFigure);
            title(app.LabUIAxes, 'L*a*b')
            xlabel(app.LabUIAxes, '')
            ylabel(app.LabUIAxes, '')
            app.LabUIAxes.XTick = [];
            app.LabUIAxes.XTickLabel = '';
            app.LabUIAxes.YTick = [];
            app.LabUIAxes.YTickLabel = '';
            app.cluster1UIAxes = uiaxes(app.UIFigure);
            title(app.cluster1UIAxes, 'Cluster-1')
            xlabel(app.cluster1UIAxes, '')
            ylabel(app.cluster1UIAxes, '')
            app.cluster1UIAxes.XTick = [];
            app.cluster1UIAxes.XTickLabel = '';
            app.cluster1UIAxes.YTick = [];
            app.cluster1UIAxes.YTickLabel = '';
            app.ReadInputImageButton = uibutton(app.UIFigure, 'push');
            app.ReadInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadInputImageButtonPushed, true);
            app.ReadInputImageButton.Text = 'Read Input Image';
            app.GrayPreprocessButton = uibutton(app.UIFigure, 'push');
            app.GrayPreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @GrayPreprocessButtonPushed, true);
            app.GrayPreprocessButton.Text = 'Gray-Preprocess';
            app.RGBtoLabButton = uibutton(app.UIFigure, 'push');
            app.RGBtoLabButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoLabButtonPushed, true);
            app.RGBtoLabButton.Text = 'RGB to L*a*b';
            app.OtsuThresholdingButton = uibutton(app.UIFigure, 'push');
            app.OtsuThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @OtsuThresholdingButtonPushed, true);
            app.OtsuThresholdingButton.Text = 'Otsu Thresholding';
            app.KmeansClustringButton = uibutton(app.UIFigure, 'push');
            app.KmeansClustringButton.ButtonPushedFcn = createCallbackFcn(app, @KmeansClustringButtonPushed, true);
            app.KmeansClustringButton.Text = 'K-means Clustring';
            app.DetectionGrayScaleButton = uibutton(app.UIFigure, 'push');
            app.DetectionGrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionGrayScaleButtonPushed, true);
            app.DetectionGrayScaleButton.Text = 'Detection (Gray Scale)';
            app.DetectionColorSpaceButton = uibutton(app.UIFigure, 'push');
            app.DetectionColorSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionColorSpaceButtonPushed, true);
            app.DetectionColorSpaceButton.Text = 'Detection (Color Space)';
            app.FeatureExtractionGrayButton = uibutton(app.UIFigure, 'push');
            app.FeatureExtractionGrayButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureExtractionGrayButtonPushed, true);
            app.FeatureExtractionGrayButton.Text = 'Feature Extraction (Gray)';
            app.FeatureExtractionColorButton = uibutton(app.UIFigure, 'push');
            app.FeatureExtractionColorButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureExtractionColorButtonPushed, true);
            app.FeatureExtractionColorButton.Text = 'Feature Extraction (Color)';
            app.ClassificationGrayButton = uibutton(app.UIFigure, 'push');
            app.ClassificationGrayButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationGrayButtonPushed, true);
            app.ClassificationGrayButton.Text = 'Classification (Gray)';
            app.ClassificationColorButton = uibutton(app.UIFigure, 'push');
            app.ClassificationColorButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationColorButtonPushed, true);
            app.ClassificationColorButton.Text = 'Classification (Color)';
            app.cluster2UIAxes = uiaxes(app.UIFigure);
            title(app.cluster2UIAxes, 'Cluster-2')
            xlabel(app.cluster2UIAxes, '')
            ylabel(app.cluster2UIAxes, '')
            app.cluster2UIAxes.XTick = [];
            app.cluster2UIAxes.XTickLabel = '';
            app.cluster2UIAxes.YTick = [];
            app.cluster2UIAxes.YTickLabel = '';
            app.cluster3UIAxes = uiaxes(app.UIFigure);
            title(app.cluster3UIAxes, 'Cluster-3')
            xlabel(app.cluster3UIAxes, '')
            ylabel(app.cluster3UIAxes, '')
            app.cluster3UIAxes.XTick = [];
            app.cluster3UIAxes.XTickLabel = '';
            app.cluster3UIAxes.YTick = [];
            app.cluster3UIAxes.YTickLabel = '';
            app.OtsuThresholdingUIAxes = uiaxes(app.UIFigure);
            title(app.OtsuThresholdingUIAxes, 'Otsu Thresholding')
            xlabel(app.OtsuThresholdingUIAxes, '')
            ylabel(app.OtsuThresholdingUIAxes, '')
            app.OtsuThresholdingUIAxes.XTick = [];
            app.OtsuThresholdingUIAxes.XTickLabel = '';
            app.OtsuThresholdingUIAxes.YTick = [];
            app.OtsuThresholdingUIAxes.YTickLabel = '';
            app.ROIGrayUIAxes = uiaxes(app.UIFigure);
            title(app.ROIGrayUIAxes, 'ROI & Edge Detection')
            xlabel(app.ROIGrayUIAxes, '')
            ylabel(app.ROIGrayUIAxes, '')
            app.ROIGrayUIAxes.XTick = [];
            app.ROIGrayUIAxes.XTickLabel = '';
            app.ROIGrayUIAxes.YTick = [];
            app.ROIGrayUIAxes.YTickLabel = '';
            app.ROIColorUIAxes = uiaxes(app.UIFigure);
            title(app.ROIColorUIAxes, 'ROI & Sobel Edge Detection')
            xlabel(app.ROIColorUIAxes, '')
            ylabel(app.ROIColorUIAxes, '')
            app.ROIColorUIAxes.XTick = [];
            app.ROIColorUIAxes.XTickLabel = '';
            app.ROIColorUIAxes.YTick = [];
            app.ROIColorUIAxes.YTickLabel = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Sugar Cane Leaf Disease Detection '};
            app.FeaturesGrayTextArea = uitextarea(app.UIFigure);
            app.FeaturesGrayTextArea.Value = {'Features (Gray)'};
            app.ContrastTextAreaLabel = uilabel(app.UIFigure);
            app.ContrastTextAreaLabel.Text = 'Contrast';
            app.ContrastTextArea = uitextarea(app.UIFigure);
            app.CorrelationTextAreaLabel = uilabel(app.UIFigure);
            app.CorrelationTextAreaLabel.Text = 'Correlation';
            app.CorrelationTextArea = uitextarea(app.UIFigure);
            app.EnergyTextAreaLabel = uilabel(app.UIFigure);
            app.EnergyTextAreaLabel.Text = 'Energy';
            app.EnergyTextArea = uitextarea(app.UIFigure);
            app.HomogeneityTextAreaLabel = uilabel(app.UIFigure);
            app.HomogeneityTextAreaLabel.Text = 'Homogeneity';
            app.HomogeneityTextArea = uitextarea(app.UIFigure);
            app.MeanTextAreaLabel = uilabel(app.UIFigure);
            app.MeanTextAreaLabel.Text = 'Mean';
            app.MeanTextArea = uitextarea(app.UIFigure);
            app.EntropyTextAreaLabel = uilabel(app.UIFigure);
            app.EntropyTextAreaLabel.Text = 'Entropy';
            app.EntropyTextArea = uitextarea(app.UIFigure);
            app.StandardDeviationTextAreaLabel = uilabel(app.UIFigure);
            app.StandardDeviationTextAreaLabel.Text = 'Standard Deviation';
            app.StandardDeviationTextArea = uitextarea(app.UIFigure);
            app.RMSTextAreaLabel = uilabel(app.UIFigure);
            app.RMSTextAreaLabel.Text = 'RMS';
            app.RMSTextArea = uitextarea(app.UIFigure);
            app.VarianceTextAreaLabel = uilabel(app.UIFigure);
            app.VarianceTextAreaLabel.Text = 'Variance';
            app.VarianceTextArea = uitextarea(app.UIFigure);
            app.SmoothnessTextAreaLabel = uilabel(app.UIFigure);
            app.SmoothnessTextAreaLabel.Text = 'Smoothness';
            app.SmoothnessTextArea = uitextarea(app.UIFigure);
            app.KurthosisTextAreaLabel = uilabel(app.UIFigure);
            app.KurthosisTextAreaLabel.Text = 'Kurthosis';
            app.KurthosisTextArea = uitextarea(app.UIFigure);
            app.SkewnessTextAreaLabel = uilabel(app.UIFigure);
            app.SkewnessTextAreaLabel.Text = 'Skewness';
            app.SkewnessTextArea = uitextarea(app.UIFigure);
            app.FeaturesGrayTextArea_2 = uitextarea(app.UIFigure);
            app.FeaturesGrayTextArea_2.Value = {'Features (Color)'};
            app.ContrastTextArea_2Label = uilabel(app.UIFigure);
            app.ContrastTextArea_2Label.Text = 'Contrast';
            app.ContrastTextArea_2 = uitextarea(app.UIFigure);
            app.CorrelationTextArea_2Label = uilabel(app.UIFigure);
            app.CorrelationTextArea_2Label.Text = 'Correlation';
            app.CorrelationTextArea_2 = uitextarea(app.UIFigure);
            app.EnergyTextArea_2Label = uilabel(app.UIFigure);
            app.EnergyTextArea_2Label.Text = 'Energy';
            app.EnergyTextArea_2 = uitextarea(app.UIFigure);
            app.HomogeneityTextArea_2Label = uilabel(app.UIFigure);
            app.HomogeneityTextArea_2Label.Text = 'Homogeneity';
            app.HomogeneityTextArea_2 = uitextarea(app.UIFigure);
            app.StandardDeviationTextArea_2Label = uilabel(app.UIFigure);
            app.StandardDeviationTextArea_2Label.Text = 'Standard Deviation';
            app.StandardDeviationTextArea_2 = uitextarea(app.UIFigure);
            app.EntropyTextArea_2Label = uilabel(app.UIFigure);
            app.EntropyTextArea_2Label.Text = 'Entropy';
            app.EntropyTextArea_2 = uitextarea(app.UIFigure);
            app.MeanTextArea_2Label = uilabel(app.UIFigure);
            app.MeanTextArea_2Label.Text = 'Mean';
            app.MeanTextArea_2 = uitextarea(app.UIFigure);
            app.VarianceTextArea_2Label = uilabel(app.UIFigure);
            app.VarianceTextArea_2Label.Text = 'Variance';
            app.VarianceTextArea_2 = uitextarea(app.UIFigure);
            app.SmoothnessTextArea_2Label = uilabel(app.UIFigure);
            app.SmoothnessTextArea_2Label.Text = 'Smoothness';
            app.SmoothnessTextArea_2 = uitextarea(app.UIFigure);
            app.SkewnessTextArea_2Label = uilabel(app.UIFigure);
            app.SkewnessTextArea_2Label.Text = 'Skewness';
            app.SkewnessTextArea_2 = uitextarea(app.UIFigure);
            app.KurthosisTextArea_2Label = uilabel(app.UIFigure);
            app.KurthosisTextArea_2Label.Text = 'Kurthosis';
            app.KurthosisTextArea_2 = uitextarea(app.UIFigure);
            app.IDMTextAreaLabel = uilabel(app.UIFigure);
            app.IDMTextAreaLabel.Text = 'IDM';
            app.IDMTextArea = uitextarea(app.UIFigure);
            app.RMSTextArea_2Label = uilabel(app.UIFigure);
            app.RMSTextArea_2Label.Text = 'RMS';
            app.RMSTextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SugarCaneLeafDiseaseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end